classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TerminarButton        matlab.ui.control.Button
        VelocidadSlider       matlab.ui.control.Slider
        VelocidadSliderLabel  matlab.ui.control.Label
        Q5Button_2            matlab.ui.control.Button
        Q5Button              matlab.ui.control.Button
        Q4Button_2            matlab.ui.control.Button
        Q4Button              matlab.ui.control.Button
        Q3Button_2            matlab.ui.control.Button
        Q3Button              matlab.ui.control.Button
        Q2Button_2            matlab.ui.control.Button
        Q2Button              matlab.ui.control.Button
        Q1Button_2            matlab.ui.control.Button
        Q1Button              matlab.ui.control.Button
    end

    
    properties (Access = private)
        s   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM10", 9600);
            fopen(app.s);
        end

        % Button pushed function: Q1Button
        function Q1ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '005'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q1Button_2
        function Q1Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '105'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q2Button
        function Q2ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '015'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q2Button_2
        function Q2Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '115'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q3Button
        function Q3ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '025'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q3Button_2
        function Q3Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '125'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4Button
        function Q4ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '035'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4Button_2
        function Q4Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '135'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q5Button
        function Q5ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '045'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q5Button_2
        function Q5Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '145'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: TerminarButton
        function TerminarButtonPushed(app, event)
            fclose(app.s);  % Cerrar el puerto serial
            delete(app.s);
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q1Button = uibutton(app.UIFigure, 'push');
            app.Q1Button.ButtonPushedFcn = createCallbackFcn(app, @Q1ButtonPushed, true);
            app.Q1Button.Text = 'Q1 +';
            app.Q1Button_2 = uibutton(app.UIFigure, 'push');
            app.Q1Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q1Button_2Pushed, true);
            app.Q1Button_2.Text = 'Q1 -';
            app.Q2Button = uibutton(app.UIFigure, 'push');
            app.Q2Button.ButtonPushedFcn = createCallbackFcn(app, @Q2ButtonPushed, true);
            app.Q2Button.Text = 'Q2 +';
            app.Q2Button_2 = uibutton(app.UIFigure, 'push');
            app.Q2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q2Button_2Pushed, true);
            app.Q2Button_2.Text = 'Q2 -';
            app.Q3Button = uibutton(app.UIFigure, 'push');
            app.Q3Button.ButtonPushedFcn = createCallbackFcn(app, @Q3ButtonPushed, true);
            app.Q3Button.Text = 'Q3 +';
            app.Q3Button_2 = uibutton(app.UIFigure, 'push');
            app.Q3Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q3Button_2Pushed, true);
            app.Q3Button_2.Text = 'Q3 -';
            app.Q4Button = uibutton(app.UIFigure, 'push');
            app.Q4Button.ButtonPushedFcn = createCallbackFcn(app, @Q4ButtonPushed, true);
            app.Q4Button.Text = 'Q4 +';
            app.Q4Button_2 = uibutton(app.UIFigure, 'push');
            app.Q4Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q4Button_2Pushed, true);
            app.Q4Button_2.Text = 'Q4 -';
            app.Q5Button = uibutton(app.UIFigure, 'push');
            app.Q5Button.ButtonPushedFcn = createCallbackFcn(app, @Q5ButtonPushed, true);
            app.Q5Button.Text = 'Q5 +';
            app.Q5Button_2 = uibutton(app.UIFigure, 'push');
            app.Q5Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q5Button_2Pushed, true);
            app.Q5Button_2.Text = 'Q5 -';
            app.VelocidadSliderLabel = uilabel(app.UIFigure);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.UIFigure);
            app.TerminarButton = uibutton(app.UIFigure, 'push');
            app.TerminarButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarButtonPushed, true);
            app.TerminarButton.Text = 'Terminar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end